classdef OpenImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        OptionsButton               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        SelectImage                 matlab.ui.control.Button
        ImageOutput1                matlab.ui.control.Image
        ImageOutput2                matlab.ui.control.Image
        ImageOutput3                matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Mask3x3Label                matlab.ui.control.Label
        Mask5x5Label                matlab.ui.control.Label
        Image2_2                    matlab.ui.control.Image
        Mask7x7Label                matlab.ui.control.Label
        Image2_3                    matlab.ui.control.Image
        Label                       matlab.ui.control.Label
        Mask3x3Label_2              matlab.ui.control.Label
        Mask5x5Label_2              matlab.ui.control.Label
        Mask7x7Label_2              matlab.ui.control.Label
        ImageOutput1_2              matlab.ui.control.Image
        ImageOutput2_2              matlab.ui.control.Image
        ImageOutput3_2              matlab.ui.control.Image
        ImageOutput1_3              matlab.ui.control.Image
        ImageOutput2_3              matlab.ui.control.Image
        ImageOutput3_3              matlab.ui.control.Image
        MinimunFilteringLabel       matlab.ui.control.Label
        MaximunFilteringLabel       matlab.ui.control.Label
        MedianFilteringLabel        matlab.ui.control.Label
        NoiseSliderLabel            matlab.ui.control.Label
        NoiseSlider                 matlab.ui.control.Slider
        AddsaltpepperNoiseCheckBox  matlab.ui.control.CheckBox
        path_image_input            matlab.ui.control.Label
        SelectNoiseaddittoimageButtonGroup  matlab.ui.container.ButtonGroup
        DontAddNoisetoImageButton   matlab.ui.control.RadioButton
        AddgaussianNoiseButton      matlab.ui.control.RadioButton
        AddspeckelNoiseButton       matlab.ui.control.RadioButton
        AddsaltpepperNoiseButton    matlab.ui.control.RadioButton
        btn_showImage_1             matlab.ui.control.Button
        btn_showImage_2             matlab.ui.control.Button
        btn_showImage_3             matlab.ui.control.Button
        btn_showImage_4             matlab.ui.control.Button
        btn_showImage_5             matlab.ui.control.Button
        btn_showImage_6             matlab.ui.control.Button
        btn_showImage_7             matlab.ui.control.Button
        btn_showImage_8             matlab.ui.control.Button
        btn_showImage_9             matlab.ui.control.Button
        BWButton_1                  matlab.ui.control.Button
        BWButton_2                  matlab.ui.control.Button
        BWButton_3                  matlab.ui.control.Button
        BWButton_4                  matlab.ui.control.Button
        BWButton_5                  matlab.ui.control.Button
        BWButton_6                  matlab.ui.control.Button
        BWButton_7                  matlab.ui.control.Button
        BWButton_8                  matlab.ui.control.Button
        BWButton_9                  matlab.ui.control.Button
        BWOrgButton                 matlab.ui.control.Button
        BWnoiseButton               matlab.ui.control.Button
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
        
    end

    methods (Access = public)
        function im_bw1,im_bw2,im_bw3,im_bw4,im_bw5,im_bw6,im_bw7,im_bw8,im_bw9,im_bw10,im_bw11 = filterSelectedImage(app)
                
                disp(' เริม่ทำฟังก์ชัน filterSelectImage');
        end
        
        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;
            
            % Update plot 
            Z = peaks(sz);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,c);
            
            % Re-enable the Plot Options button
            app.OptionsButton.Enable = 'on';
        end
        
    end
    
    methods (Access = private)
        function mouseMove(app,objectHandle, ~)
            cursorPoint = get(objectHandle.axes1, 'CurrentPoint');
            app.xCoord.Value = cursorPoint(1,1);
            app.yCoord.Value = cursorPoint(1,2);
        end
        
        function orgImage = loadOriginalImage(app)
            orgImage = imread(app.path_image_input.Text); 
        end
        
        function results = gray2rgb(app,image)
             if(size(image,3)==1)
                 results = cat(3,image,image,image);
             else
                 results = image;
             end
             
        end
        
        function addNoiseImage = add_noise1_image(app)
            orgImage = imread(app.path_image_input.Text);
            % h = fspecial('motion', 50, 45);
            addNoiseImage = imnoise(orgImage,'salt & pepper',app.NoiseSlider.Value);          
            
            if(size(addNoiseImage,3)>2)
                 addNoiseImage = rgb2gray(addNoiseImage);
            end
    
        end
              
        function addNoiseImage = add_noise2_image(app)
            orgImage = imread(app.path_image_input.Text);
            % h = fspecial('motion', 50, 45);
            addNoiseImage = imnoise(orgImage,'speckle',app.NoiseSlider.Value);
            if(size(addNoiseImage,3)>2)
                 addNoiseImage = rgb2gray(addNoiseImage);
            end
        end
        
        function addNoiseImage = add_noise3_image(app)
            orgImage = imread(app.path_image_input.Text);
            % h = fspecial('motion', 50, 45);
            addNoiseImage = imnoise(orgImage,'gaussian',app.NoiseSlider.Value);
            if(size(addNoiseImage,3)>2)
                 addNoiseImage = rgb2gray(addNoiseImage);
            end
            
        end
        
        function results = filter_noise1_process(app)
            orgImage = imread(app.path_image_input.Text);
            
            if(size(orgImage,3)>2)
                 orgImage = rgb2gray(orgImage);
            end
            
            addNoiseImage = imnoise(orgImage,'gaussian',app.NoiseSlider.Value);
            app.Image.ImageSource = gray2rgb(app,addNoiseImage);
            
            im_filtered1 = ordfilt2(addNoiseImage,1,ones(3,3)); 
            im_filtered2 = ordfilt2(addNoiseImage,9,ones(3,3));       
            im_filtered3 = ordfilt2(addNoiseImage,5,ones(3,3));
            
            im_filtered4 = ordfilt2(addNoiseImage,1,ones(5,5));   
            im_filtered5 = ordfilt2(addNoiseImage,25,ones(5,5));       
            im_filtered6 = ordfilt2(addNoiseImage,13,ones(5,5));
            
            im_filtered7 = ordfilt2(addNoiseImage,1,ones(7,7));   
            im_filtered8 = ordfilt2(addNoiseImage,49,ones(7,7));       
            im_filtered9 = ordfilt2(addNoiseImage,25,ones(7,7));
            
            
            app.ImageOutput1.ImageSource = gray2rgb(app,im_filtered1);
            app.ImageOutput1_2.ImageSource = gray2rgb(app,im_filtered2);
            app.ImageOutput1_3.ImageSource = gray2rgb(app,im_filtered3);
            
            app.ImageOutput2.ImageSource = gray2rgb(app,im_filtered4);
            app.ImageOutput2_2.ImageSource = gray2rgb(app,im_filtered5);
            app.ImageOutput2_3.ImageSource = gray2rgb(app,im_filtered6);
            
            
            app.ImageOutput3.ImageSource = gray2rgb(app,im_filtered7);
            app.ImageOutput3_2.ImageSource = gray2rgb(app,im_filtered8);
            app.ImageOutput3_3.ImageSource = gray2rgb(app,im_filtered9);

            results = '1';
                 
        end
        
       
         function results = filter_noise2_process(app)
            orgImage = imread(app.path_image_input.Text);
            
            if(size(orgImage,3)>2)
                 orgImage = rgb2gray(orgImage);
            end
            
            addNoiseImage = imnoise(orgImage,'speckle',app.NoiseSlider.Value);
            app.Image.ImageSource = gray2rgb(app,addNoiseImage);
            
            im_filtered1 = ordfilt2(addNoiseImage,1,ones(3,3));   
            im_filtered2 = ordfilt2(addNoiseImage,9,ones(3,3));       
            im_filtered3 = ordfilt2(addNoiseImage,5,ones(3,3));
            
            im_filtered4 = ordfilt2(addNoiseImage,1,ones(5,5));   
            im_filtered5 = ordfilt2(addNoiseImage,25,ones(5,5));       
            im_filtered6 = ordfilt2(addNoiseImage,13,ones(5,5));
            
            im_filtered7 = ordfilt2(addNoiseImage,1,ones(7,7));  
            im_filtered8 = ordfilt2(addNoiseImage,49,ones(7,7));       
            im_filtered9 = ordfilt2(addNoiseImage,25,ones(7,7));
            
            
            app.ImageOutput1.ImageSource = gray2rgb(app,im_filtered1);
            app.ImageOutput1_2.ImageSource = gray2rgb(app,im_filtered2);
            app.ImageOutput1_3.ImageSource = gray2rgb(app,im_filtered3);
            
            app.ImageOutput2.ImageSource = gray2rgb(app,im_filtered4);
            app.ImageOutput2_2.ImageSource = gray2rgb(app,im_filtered5);
            app.ImageOutput2_3.ImageSource = gray2rgb(app,im_filtered6);
            
            
            app.ImageOutput3.ImageSource = gray2rgb(app,im_filtered7);
            app.ImageOutput3_2.ImageSource = gray2rgb(app,im_filtered8);
            app.ImageOutput3_3.ImageSource = gray2rgb(app,im_filtered9);

            results = '1';
                 
         end
         
         function results = filter_noise3_process(app)
            orgImage = imread(app.path_image_input.Text);
            
            if(size(orgImage,3)>2)
                 orgImage = rgb2gray(orgImage);
            end
            
            addNoiseImage = imnoise(orgImage,'salt & pepper',app.NoiseSlider.Value);
            app.Image.ImageSource = gray2rgb(app,addNoiseImage);
            
            im_filtered1 = ordfilt2(addNoiseImage,1,ones(3,3));   
            im_filtered2 = ordfilt2(addNoiseImage,9,ones(3,3));       
            im_filtered3 = ordfilt2(addNoiseImage,5,ones(3,3));
            
            im_filtered4 = ordfilt2(addNoiseImage,1,ones(5,5));   
            im_filtered5 = ordfilt2(addNoiseImage,25,ones(5,5));       
            im_filtered6 = ordfilt2(addNoiseImage,13,ones(5,5));
            
            im_filtered7 = ordfilt2(addNoiseImage,1,ones(7,7));   
            im_filtered8 = ordfilt2(addNoiseImage,49,ones(7,7));       
            im_filtered9 = ordfilt2(addNoiseImage,25,ones(7,7));
            
            
            app.ImageOutput1.ImageSource = gray2rgb(app,im_filtered1);
            app.ImageOutput1_2.ImageSource = gray2rgb(app,im_filtered2);
            app.ImageOutput1_3.ImageSource = gray2rgb(app,im_filtered3);
            
            app.ImageOutput2.ImageSource = gray2rgb(app,im_filtered4);
            app.ImageOutput2_2.ImageSource = gray2rgb(app,im_filtered5);
            app.ImageOutput2_3.ImageSource = gray2rgb(app,im_filtered6);
            
            
            app.ImageOutput3.ImageSource = gray2rgb(app,im_filtered7);
            app.ImageOutput3_2.ImageSource = gray2rgb(app,im_filtered8);
            app.ImageOutput3_3.ImageSource = gray2rgb(app,im_filtered9);

            results = '1';
                 
        end


        function results = show_big_image(app,id)
%            disp(app.Knob.Value);
             orgImage = imread(app.path_image_input.Text);
            
                if(size(orgImage,3)>2)
                     orgImage = rgb2gray(orgImage);
                end
                if(app.AddgaussianNoiseButton.Value == 1 )
                    addNoiseImage = imnoise(orgImage,'gaussian',app.NoiseSlider.Value);
                elseif(app.AddspeckelNoiseButton.Value == 1)
                    addNoiseImage = imnoise(orgImage,'speckel',app.NoiseSlider.Value);
                elseif(app.AddsaltpepperNoiseButton.Value == 1) 
                    addNoiseImage = imnoise(orgImage,'salt & pepper',app.NoiseSlider.Value); 
                else
                    addNoiseImage = orgImage;
                end
                
                app.Image.ImageSource = gray2rgb(app,addNoiseImage);
                
                im_filtered1 = ordfilt2(addNoiseImage,1,ones(3,3));   
                im_filtered2 = ordfilt2(addNoiseImage,9,ones(3,3));       
                im_filtered3 = ordfilt2(addNoiseImage,5,ones(3,3));
                
                im_filtered4 = ordfilt2(addNoiseImage,1,ones(5,5));   
                im_filtered5 = ordfilt2(addNoiseImage,25,ones(5,5));       
                im_filtered6 = ordfilt2(addNoiseImage,13,ones(5,5));
                ;
                im_filtered7 = ordfilt2(addNoiseImage,1,ones(7,7));  
                im_filtered8 = ordfilt2(addNoiseImage,49,ones(7,7));       
                im_filtered9 = ordfilt2(addNoiseImage,25,ones(7,7));
                
                 figure;          
             if(id == 1)
                  imshowpair(orgImage,im_filtered1,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 2) 
                  imshowpair(orgImage,im_filtered2,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 3)
                  imshowpair(orgImage,im_filtered3,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 4)
                  imshowpair(orgImage,im_filtered4,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 5)
                   imshowpair(orgImage,im_filtered5,'montage');
                   title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 6)
                  imshowpair(orgImage,im_filtered6,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 7)
                  imshowpair(orgImage,im_filtered7,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 8)
                  imshowpair(orgImage,im_filtered8,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             elseif (id == 9)
                  imshowpair(orgImage,im_filtered9,'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ผ่านการปรับปรุง')
             else
                   imshow(addNoiseImage);
             end
            
        end
        
        function results = show_bw_image(app,id)
            
                orgImage = imread(app.path_image_input.Text);
            
                if(size(orgImage,3)>2)
                     orgImage = rgb2gray(orgImage);
                end
                if(app.AddgaussianNoiseButton.Value == 1 )
                    addNoiseImage = imnoise(orgImage,'gaussian',app.NoiseSlider.Value);
                elseif(app.AddspeckelNoiseButton.Value == 1)
                    addNoiseImage = imnoise(orgImage,'speckel',app.NoiseSlider.Value);
                elseif(app.AddsaltpepperNoiseButton.Value == 1) 
                    addNoiseImage = imnoise(orgImage,'salt & pepper',app.NoiseSlider.Value); 
                else
                    addNoiseImage = orgImage;
                end
                
                app.Image.ImageSource = gray2rgb(app,addNoiseImage);
                
                im_filtered1 = ordfilt2(addNoiseImage,1,ones(3,3));   
                im_bw1       =   imbinarize(imcomplement(im_filtered1));
                im_bw1       =    bwskel(im_bw1); 
                im_bw1       =   labeloverlay(orgImage,im_bw1,'Transparency',0);
                
                im_filtered2 = ordfilt2(addNoiseImage,9,ones(3,3));   
                im_bw2       =   imbinarize(imcomplement(im_filtered2));
                im_bw2       =   bwskel(im_bw2); 
                im_bw2       =   labeloverlay(orgImage,im_bw2,'Transparency',0);
                
                im_filtered3 = ordfilt2(addNoiseImage,5,ones(3,3));
                im_bw3       =    imbinarize(imcomplement(im_filtered3));
                im_bw3       =    bwskel(im_bw3); 
                im_bw3       =   labeloverlay(orgImage,im_bw3,'Transparency',0);
                
                im_filtered4 = ordfilt2(addNoiseImage,1,ones(5,5));  
                im_bw4       =    imbinarize(imcomplement(im_filtered4));
                im_bw4       =    bwskel(im_bw4); 
                im_bw4       =   labeloverlay(orgImage,im_bw4,'Transparency',0);
                
                im_filtered5 = ordfilt2(addNoiseImage,25,ones(5,5));
                im_bw5       =    imbinarize(imcomplement(im_filtered5));
                im_bw5       =    bwskel(im_bw5);
                im_bw5       =   labeloverlay(orgImage,im_bw5,'Transparency',0);
                
                im_filtered6 = ordfilt2(addNoiseImage,13,ones(5,5));
                im_bw6       =    imbinarize(imcomplement(im_filtered6));
                im_bw6       =    bwskel(im_bw6);
                im_bw6       =   labeloverlay(orgImage,im_bw6,'Transparency',0);
                
                im_filtered7 = ordfilt2(addNoiseImage,1,ones(7,7));  
                im_bw7      =    imbinarize(imcomplement(im_filtered7));
                im_bw7       =    bwskel(im_bw7);
                im_bw7       =   labeloverlay(orgImage,im_bw7,'Transparency',0);
                
                im_filtered8 = ordfilt2(addNoiseImage,49,ones(7,7));       
                im_bw8       =    imbinarize(imcomplement(im_filtered8));
                im_bw8       =    bwskel(im_bw8);
                im_bw8       =   labeloverlay(orgImage,im_bw8,'Transparency',0);
                
                im_filtered9 = ordfilt2(addNoiseImage,25,ones(7,7));
                im_bw9       =    imbinarize(imcomplement(im_filtered9));
                im_bw9       =    bwskel(im_bw9);
                im_bw9       =   labeloverlay(orgImage,im_bw9,'Transparency',0);
                
                im_filtered10 = orgImage;
                im_bw10       =    imbinarize(imcomplement(im_filtered10));
                im_bw10       =    bwskel(im_bw10);
                im_bw10      =   labeloverlay(orgImage,im_bw10,'Transparency',0);
                
                im_filtered11 = addNoiseImage;
                im_bw11       =    imbinarize(imcomplement(im_filtered11));
                im_bw11       =    bwskel(im_bw11);
                im_bw11       =   labeloverlay(orgImage,im_bw11,'Transparency',0);
                
                 figure;          
             if(id == 1)
                  imshowpair(im_bw11 ,labeloverlay(orgImage,im_bw1,'Transparency',0),'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 2) 
                  imshowpair(im_bw11,im_bw2,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 3)
                  imshowpair(im_bw11 ,im_bw3,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 4)
                  imshowpair(im_bw11 ,im_bw4,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 5)
                   imshowpair(im_bw11 ,im_bw5,'montage');
                   title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 6)
                  imshowpair(im_bw11 ,im_bw6,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 7)
                  imshowpair(im_bw11 ,im_bw7,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 8)
                  imshowpair(im_bw11 ,im_bw8,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 9)
                  imshowpair(im_bw11 ,im_bw9,'montage');
                  title('ภาพต้นฉบับเพิ่มสัญญาณรบกวนไม่ปรับปรุง        ภาพที่ผ่านการปรับปรุงแล้ว');
             elseif (id == 10)
                   imshowpair(orgImage,bwskel(imbinarize(imcomplement(orgImage))),'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ได้จากต้นฉบับ')
             elseif (id == 11)
                  imshowpair(addNoiseImage,im_bw11,'montage');
                  title(' ภาพเพิ่มสัญญาณรบกวน                ภาพที่ไม่ผ่านการปรับปรุงแต่มีสัญญาณรบกวน')
             else
                   imshowpair(orgImage,bwskel(imbinarize(imcomplement(orgImage))),'montage');
                  title('ภาพต้นฉบับ                   ภาพที่ได้จากต้นฉบับ')
             end
             
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call updateplot to display an initial plot
           
            updateplot(app, app.CurrentSize, app.CurrentColormap)
            path = pwd;
            file = 'img.jpg';
            
            app.path_image_input.Text = fullfile(path,file);
            app.NoiseSlider.Value = 0.06;


            app.Image.ImageSource = fullfile(path,file);
           
            
            app.ImageOutput1.ImageSource = fullfile(path,file);
            app.ImageOutput2.ImageSource = fullfile(path,file);
            app.ImageOutput3.ImageSource = fullfile(path,file);
            
            app.ImageOutput1_2.ImageSource = fullfile(path,file);
            app.ImageOutput2_2.ImageSource = fullfile(path,file);
            app.ImageOutput3_2.ImageSource = fullfile(path,file);
            
            
            app.ImageOutput1_3.ImageSource = fullfile(path,file);
            app.ImageOutput2_3.ImageSource = fullfile(path,file);
            app.ImageOutput3_3.ImageSource = fullfile(path,file);
            
            
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogAppExample(app, app.CurrentSize, app.CurrentColormap);
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Callback function: Image, SelectImage
        function SelectImagePushed(app, event)
        %เมื่อปุ่มถูก เลือกภาพที่ต้องการ ถูกกด
            [file,path] = uigetfile('*.jpg;*.png;*.gif');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
            app.path_image_input.Text =fullfile(path,file);
            app.Image.ImageSource = fullfile(path,file); 
            
            %path = pwd;
            %file = 'img.jpg';
            
            app.ImageOutput1.ImageSource = fullfile(path,file);
            app.ImageOutput2.ImageSource = fullfile(path,file);
            app.ImageOutput3.ImageSource = fullfile(path,file);
            
            app.ImageOutput1_2.ImageSource = fullfile(path,file);
            app.ImageOutput2_2.ImageSource = fullfile(path,file);
            app.ImageOutput3_2.ImageSource = fullfile(path,file);
            
            
            app.ImageOutput1_3.ImageSource = fullfile(path,file);
            app.ImageOutput2_3.ImageSource = fullfile(path,file);
            app.ImageOutput3_3.ImageSource = fullfile(path,file);


        end

        % Value changed function: AddsaltpepperNoiseCheckBox
        function AddsaltpepperNoiseCheckBoxValueChanged(app, event)
            value = app.AddsaltpepperNoiseCheckBox.Value;
            disp(value)
            if(value == 1)
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวนในภาพให้แสดง ภาพที่มีสัญญาณรบกวน ที่หน้าจอ ");
                
              %  app.Image.ImageSource = 
               app.Image.ImageSource =  add_noise1_image(app);
            else
               app.Image.ImageSource =  loadOriginalImage(app); 
            end
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;
%             app.Image.ImageSource = add_noise1_image(app);
%             disp(value)
             if(app.AddgaussianNoiseButton.Value == 1)
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 1 gaussian Noise ");
                app.Image.ImageSource = gray2rgb(app,add_noise1_image(app));
                filter_noise1_process(app);
                
            elseif(app.AddspeckelNoiseButton.Value == 1)
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 2 speckelNoise");
                app.Image.ImageSource =  gray2rgb(app,add_noise2_image(app));
                filter_noise2_process(app);
                
            elseif(app.AddsaltpepperNoiseButton.Value == 1)            
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 3 saltpepperNoise");
                app.Image.ImageSource =  gray2rgb(app,add_noise3_image(app));
                filter_noise3_process(app);
                
            else
                disp(" ภาพต้นฉบับ ");
                app.Image.ImageSource = loadOriginalImage(app);
            end
        end

        % Selection changed function: 
        % SelectNoiseaddittoimageButtonGroup
        function SelectNoiseaddittoimageButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectNoiseaddittoimageButtonGroup.SelectedObject;
            disp(app.DontAddNoisetoImageButton.Value);
             if(app.AddgaussianNoiseButton.Value == 1)
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 1 gaussian Noise "); 
                filter_noise1_process(app);
                
            elseif(app.AddspeckelNoiseButton.Value == 1)
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 2 speckelNoise");
                filter_noise2_process(app);
                
            elseif(app.AddsaltpepperNoiseButton.Value == 1)            
                disp(" เมื่อมีการกดเลือก เพิ่มสัญญาณรบกวน Type 3 saltpepperNoise");
                filter_noise3_process(app);
            else
                disp(" ภาพต้นฉบับ ");
                app.Image.ImageSource = loadOriginalImage(app);
            end
        end

        % Button pushed function: btn_showImage_1
        function btn_showImage_1ButtonPushed(app, event)
             show_big_image(app,1);
        end

        % Button pushed function: btn_showImage_2
        function btn_showImage_2ButtonPushed(app, event)
            show_big_image(app,2);
        end

        % Button pushed function: btn_showImage_3
        function btn_showImage_3ButtonPushed(app, event)
            show_big_image(app,3);
        end

        % Button pushed function: btn_showImage_4
        function btn_showImage_4ButtonPushed(app, event)
            show_big_image(app,4);
        end

        % Button pushed function: btn_showImage_5
        function btn_showImage_5ButtonPushed(app, event)
            show_big_image(app,5);
        end

        % Button pushed function: btn_showImage_6
        function btn_showImage_6ButtonPushed(app, event)
            show_big_image(app,6);
        end

        % Button pushed function: btn_showImage_7
        function btn_showImage_7ButtonPushed(app, event)
            show_big_image(app,7); 
        end

        % Button pushed function: btn_showImage_8
        function btn_showImage_8ButtonPushed(app, event)
           show_big_image(app,8); 
        end

        % Button pushed function: btn_showImage_9
        function btn_showImage_9ButtonPushed(app, event)
           show_big_image(app,9);  
        end

        % Button pushed function: BWButton_1
        function BWButton_1Pushed(app, event)
           show_bw_image(app,1);   
        end

        % Button pushed function: BWButton_2
        function BWButton_2Pushed(app, event)
            show_bw_image(app,2);
        end

        % Button pushed function: BWButton_3
        function BWButton_3Pushed(app, event)
            show_bw_image(app,3); 
        end

        % Button pushed function: BWButton_4
        function BWButton_4Pushed(app, event)
            show_bw_image(app,4); 
        end

        % Button pushed function: BWButton_5
        function BWButton_5Pushed(app, event)
            show_bw_image(app,5);
        end

        % Button pushed function: BWButton_6
        function BWButton_6Pushed(app, event)
            show_bw_image(app,6); 
        end

        % Button pushed function: BWButton_7
        function BWButton_7Pushed(app, event)
            show_bw_image(app,7);
        end

        % Button pushed function: BWButton_8
        function BWButton_8Pushed(app, event)
            show_bw_image(app,8); 
        end

        % Button pushed function: BWButton_9
        function BWButton_9Pushed(app, event)
            show_bw_image(app,9);
        end

        % Button pushed function: BWOrgButton
        function BWOrgButtonPushed(app, event)
            show_bw_image(app,10);
        end

        % Button pushed function: BWnoiseButton
        function BWnoiseButtonPushed(app, event)
            show_bw_image(app,11);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Display Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SelectImagePushed, true);
            app.SelectImage = uibutton(app.UIFigure, 'push');
            app.SelectImage.ButtonPushedFcn = createCallbackFcn(app, @SelectImagePushed, true);
            app.SelectImage.Icon = 'icon4.png';
            app.SelectImage.Text = 'เลือกภาพที่ต้องการ..';
            app.ImageOutput1 = uiimage(app.UIFigure);
            app.ImageOutput2 = uiimage(app.UIFigure);
            app.ImageOutput3 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'mask3x3.jpg';
            app.Mask3x3Label = uilabel(app.UIFigure);
            app.Mask3x3Label.Text = 'Mask 3 x 3';
            app.Mask5x5Label = uilabel(app.UIFigure);
            app.Mask5x5Label.Text = 'Mask 5 x 5';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'mask5x5.jpg';
            app.Mask7x7Label = uilabel(app.UIFigure);
            app.Mask7x7Label.Text = 'Mask 7 x 7';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'mask7x7.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ภาพต้นฉบับ';
            app.Mask3x3Label_2 = uilabel(app.UIFigure);
            app.Mask3x3Label_2.Text = 'ภาพผลลัพธ์ Mask 3 x 3';
            app.Mask5x5Label_2 = uilabel(app.UIFigure);
            app.Mask5x5Label_2.Text = 'ภาพผลลัพธ์ Mask 5 x 5';
            app.Mask7x7Label_2 = uilabel(app.UIFigure);
            app.Mask7x7Label_2.Text = 'ภาพผลลัพธ์ Mask 7 x 7';
            app.ImageOutput1_2 = uiimage(app.UIFigure);
            app.ImageOutput2_2 = uiimage(app.UIFigure);
            app.ImageOutput3_2 = uiimage(app.UIFigure);
            app.ImageOutput1_3 = uiimage(app.UIFigure);
            app.ImageOutput2_3 = uiimage(app.UIFigure);
            app.ImageOutput3_3 = uiimage(app.UIFigure);
            app.MinimunFilteringLabel = uilabel(app.UIFigure);
            app.MinimunFilteringLabel.Text = 'Minimun Filtering';
            app.MaximunFilteringLabel = uilabel(app.UIFigure);
            app.MaximunFilteringLabel.Text = 'Maximun Filtering';
            app.MedianFilteringLabel = uilabel(app.UIFigure);
            app.MedianFilteringLabel.Text = 'Median Filtering';
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0 1];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.AddsaltpepperNoiseCheckBox = uicheckbox(app.UIFigure);
            app.AddsaltpepperNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddsaltpepperNoiseCheckBoxValueChanged, true);
            app.AddsaltpepperNoiseCheckBox.Visible = 'off';
            app.AddsaltpepperNoiseCheckBox.Text = 'Add salt & pepper Noise';
            app.path_image_input = uilabel(app.UIFigure);
            app.SelectNoiseaddittoimageButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectNoiseaddittoimageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectNoiseaddittoimageButtonGroupSelectionChanged, true);
            app.SelectNoiseaddittoimageButtonGroup.Title = 'Select Noise add it to image..';
            app.DontAddNoisetoImageButton = uiradiobutton(app.SelectNoiseaddittoimageButtonGroup);
            app.DontAddNoisetoImageButton.Text = 'Don''t Add Noise to Image';
            app.DontAddNoisetoImageButton.Value = true;
            app.AddgaussianNoiseButton = uiradiobutton(app.SelectNoiseaddittoimageButtonGroup);
            app.AddgaussianNoiseButton.Text = 'Add  gaussian Noise';
            app.AddspeckelNoiseButton = uiradiobutton(app.SelectNoiseaddittoimageButtonGroup);
            app.AddspeckelNoiseButton.Text = 'Add speckel Noise';
            app.AddsaltpepperNoiseButton = uiradiobutton(app.SelectNoiseaddittoimageButtonGroup);
            app.AddsaltpepperNoiseButton.Text = 'Add salt & pepper Noise';
            app.btn_showImage_1 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_1.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_1ButtonPushed, true);
            app.btn_showImage_1.Icon = 'icon3.png';
            app.btn_showImage_1.Text = 'Show';
            app.btn_showImage_2 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_2.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_2ButtonPushed, true);
            app.btn_showImage_2.Icon = 'icon3.png';
            app.btn_showImage_2.Text = 'Show';
            app.btn_showImage_3 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_3.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_3ButtonPushed, true);
            app.btn_showImage_3.Icon = 'icon3.png';
            app.btn_showImage_3.Text = 'Show';
            app.btn_showImage_4 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_4.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_4ButtonPushed, true);
            app.btn_showImage_4.Icon = 'icon3.png';
            app.btn_showImage_4.Text = 'Show';
            app.btn_showImage_5 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_5.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_5ButtonPushed, true);
            app.btn_showImage_5.Icon = 'icon3.png';
            app.btn_showImage_5.Text = 'Show';
            app.btn_showImage_6 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_6.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_6ButtonPushed, true);
            app.btn_showImage_6.Icon = 'icon3.png';
            app.btn_showImage_6.Text = 'Show';
            app.btn_showImage_7 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_7.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_7ButtonPushed, true);
            app.btn_showImage_7.Icon = 'icon3.png';
            app.btn_showImage_7.Text = 'Show';
            app.btn_showImage_8 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_8.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_8ButtonPushed, true);
            app.btn_showImage_8.Icon = 'icon3.png';
            app.btn_showImage_8.Text = 'Show';
            app.btn_showImage_9 = uibutton(app.UIFigure, 'push');
            app.btn_showImage_9.ButtonPushedFcn = createCallbackFcn(app, @btn_showImage_9ButtonPushed, true);
            app.btn_showImage_9.Icon = 'icon3.png';
            app.btn_showImage_9.Text = 'Show';
            app.BWButton_1 = uibutton(app.UIFigure, 'push');
            app.BWButton_1.ButtonPushedFcn = createCallbackFcn(app, @BWButton_1Pushed, true);
            app.BWButton_1.Icon = 'icon2.png';
            app.BWButton_1.Text = 'BW ';
            app.BWButton_2 = uibutton(app.UIFigure, 'push');
            app.BWButton_2.ButtonPushedFcn = createCallbackFcn(app, @BWButton_2Pushed, true);
            app.BWButton_2.Icon = 'icon2.png';
            app.BWButton_2.Text = 'BW ';
            app.BWButton_3 = uibutton(app.UIFigure, 'push');
            app.BWButton_3.ButtonPushedFcn = createCallbackFcn(app, @BWButton_3Pushed, true);
            app.BWButton_3.Icon = 'icon2.png';
            app.BWButton_3.Text = 'BW ';
            app.BWButton_4 = uibutton(app.UIFigure, 'push');
            app.BWButton_4.ButtonPushedFcn = createCallbackFcn(app, @BWButton_4Pushed, true);
            app.BWButton_4.Icon = 'icon2.png';
            app.BWButton_4.Text = 'BW ';
            app.BWButton_5 = uibutton(app.UIFigure, 'push');
            app.BWButton_5.ButtonPushedFcn = createCallbackFcn(app, @BWButton_5Pushed, true);
            app.BWButton_5.Icon = 'icon2.png';
            app.BWButton_5.Text = 'BW ';
            app.BWButton_6 = uibutton(app.UIFigure, 'push');
            app.BWButton_6.ButtonPushedFcn = createCallbackFcn(app, @BWButton_6Pushed, true);
            app.BWButton_6.Icon = 'icon2.png';
            app.BWButton_6.Text = 'BW ';
            app.BWButton_7 = uibutton(app.UIFigure, 'push');
            app.BWButton_7.ButtonPushedFcn = createCallbackFcn(app, @BWButton_7Pushed, true);
            app.BWButton_7.Icon = 'icon2.png';
            app.BWButton_7.Text = 'BW ';
            app.BWButton_8 = uibutton(app.UIFigure, 'push');
            app.BWButton_8.ButtonPushedFcn = createCallbackFcn(app, @BWButton_8Pushed, true);
            app.BWButton_8.Icon = 'icon2.png';
            app.BWButton_8.Text = 'BW ';
            app.BWButton_9 = uibutton(app.UIFigure, 'push');
            app.BWButton_9.ButtonPushedFcn = createCallbackFcn(app, @BWButton_9Pushed, true);
            app.BWButton_9.Icon = 'icon2.png';
            app.BWButton_9.Text = 'BW ';
            app.BWOrgButton = uibutton(app.UIFigure, 'push');
            app.BWOrgButton.ButtonPushedFcn = createCallbackFcn(app, @BWOrgButtonPushed, true);
            app.BWOrgButton.Icon = 'icon2.png';
            app.BWOrgButton.Text = 'BW  Org';
            app.BWnoiseButton = uibutton(app.UIFigure, 'push');
            app.BWnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @BWnoiseButtonPushed, true);
            app.BWnoiseButton.Icon = 'icon2.png';
            app.BWnoiseButton.Text = 'BW  noise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenImage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end